classdef Main_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputmoneyButton            matlab.ui.control.Button
        RefundButton                matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        ADDVendingmachineLabel      matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        Image4                      matlab.ui.control.Image
        MaintenanceInterfaceButton  matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        EditField_2                 matlab.ui.control.EditField
        EditField_3                 matlab.ui.control.EditField
        EditField_4                 matlab.ui.control.EditField
        Button_5                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        Button_8                    matlab.ui.control.Button
        Label_6                     matlab.ui.control.Label
        StateLabel                  matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        ChosenEditFieldLabel        matlab.ui.control.Label
        ChosenEditField             matlab.ui.control.NumericEditField
        ChosenEditField_2Label      matlab.ui.control.Label
        ChosenEditField_2           matlab.ui.control.NumericEditField
        ChosenEditField_3Label      matlab.ui.control.Label
        ChosenEditField_3           matlab.ui.control.NumericEditField
        ChosenEditField_4Label      matlab.ui.control.Label
        TotalCostEditFieldLabel     matlab.ui.control.Label
        TotalCostEditField          matlab.ui.control.NumericEditField
        ThereareEditFieldLabel      matlab.ui.control.Label
        ThereareEditField           matlab.ui.control.NumericEditField
        Label_8                     matlab.ui.control.Label
        BuyButton                   matlab.ui.control.Button
        leftLabel                   matlab.ui.control.Label
        EditField_5                 matlab.ui.control.NumericEditField
        ChosenEditField_4           matlab.ui.control.NumericEditField
        SUMEditFieldLabel           matlab.ui.control.Label
        SUMEditField                matlab.ui.control.NumericEditField
        Label_9                     matlab.ui.control.Label
        Item1TextAreaLabel          matlab.ui.control.Label
        Item1TextArea               matlab.ui.control.TextArea
        ExitofmerchandiseLabel      matlab.ui.control.Label
        Item2TextArea_2Label        matlab.ui.control.Label
        Item2TextArea_2             matlab.ui.control.TextArea
        Item3TextAreaLabel          matlab.ui.control.Label
        Item3TextArea               matlab.ui.control.TextArea
        Item4TextAreaLabel          matlab.ui.control.Label
        Item4TextArea               matlab.ui.control.TextArea
        LeftEditFieldLabel          matlab.ui.control.Label
        LeftEditField               matlab.ui.control.NumericEditField
        LeftEditField_2Label        matlab.ui.control.Label
        LeftEditField_2             matlab.ui.control.NumericEditField
        LeftEditField_3Label        matlab.ui.control.Label
        LeftEditField_3             matlab.ui.control.NumericEditField
        LeftEditField_4Label        matlab.ui.control.Label
        LeftEditField_4             matlab.ui.control.NumericEditField
        FAKEButton                  matlab.ui.control.Button
        COINButton                  matlab.ui.control.Button
        coinLabel                   matlab.ui.control.Label
        EditField_6                 matlab.ui.control.NumericEditField
        Label_10                    matlab.ui.control.Label
        Label_11                    matlab.ui.control.Label
        Label_12                    matlab.ui.control.Label
        Label_13                    matlab.ui.control.Label
        Label_14                    matlab.ui.control.Label
        EditField_7                 matlab.ui.control.NumericEditField
        EditField_8                 matlab.ui.control.NumericEditField
        EditField_9                 matlab.ui.control.NumericEditField
        EditField_10                matlab.ui.control.NumericEditField
        Label_15                    matlab.ui.control.Label
        EditField_11                matlab.ui.control.NumericEditField
        cEditField                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
       s1 = 12;  %surplus of item1;
       s2 = 10;  %surplus of item2;
       s3 = 8;   %surplus of item3;
       s4 = 5;   %surplus of item4;
       c1 = 0; % the amount of item1
       c2 = 0; % the amount of item2
       c3 = 0; % the amount of item3
       c4 = 0; % the amount of item4
       p = 0;  % the total price of items
       lft = 0;% the left money
       another;% the password app
       amount = 0;% the money earned by the machine
       a0=100,a1=100,a5=100,a10=100,a20=100,a50=100,a100=100;
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.LeftEditField.Value = app.s1;
             app.LeftEditField_2.Value = app.s2;
             app.LeftEditField_3.Value = app.s3;
             app.LeftEditField_4.Value = app.s4;
             
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.c1=app.c1+1;% amount + 1
            app.ChosenEditField.Value = app.c1; 
            app.p = app.p+2;% cost + 2
            app.TotalCostEditField.Value = app.p;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if(app.c1==0)
                return;
            end
            app.c1=app.c1-1;
            app.ChosenEditField.Value = app.c1;
            app.p = app.p-2;
            app.TotalCostEditField.Value = app.p;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.c2=app.c2+1;
            app.ChosenEditField_2.Value = app.c2;
            app.p = app.p+3;
            app.TotalCostEditField.Value = app.p;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if(app.c2==0)
                return;
            end
            app.c2=app.c2-1;
            app.ChosenEditField_2.Value = app.c2;
            app.p = app.p-3;
            app.TotalCostEditField.Value = app.p;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.c3=app.c3+1;
            app.ChosenEditField_3.Value = app.c3;
            app.p = app.p+5;
            app.TotalCostEditField.Value = app.p;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if(app.c3==0)
                return;
            end     
            app.c3=app.c3-1;
            app.ChosenEditField_3.Value = app.c3;
            app.p = app.p-5;
            app.TotalCostEditField.Value = app.p;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.c4=app.c4+1;
            app.ChosenEditField_4.Value = app.c4;
            app.p = app.p+10;
            app.TotalCostEditField.Value = app.p;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if(app.c4==0)
                return;
            end
            app.c4=app.c4-1;
            app.ChosenEditField_4.Value = app.c4;
            app.p = app.p-10;
            app.TotalCostEditField.Value = app.p;
        end

        % Button pushed function: InputmoneyButton
        function InputmoneyButtonPushed(app, event)
             if app.a1>=999||app.a5>=999||app.a10>=999||app.a20>=999||app.a50>=999||app.a100>=999 
                app.TextArea_2 = "Error3";              % 在某种钱币在上一次达到999张后 或 在塞第1000张时，状态会变Error3, 为了防止恶意塞钱
                app.TextArea_2.FontColor = [1.00,0.00,0.00];
             end
             
            if isequal(app.TextArea_2.Value,"Error3")   %  if state is error3, can't input  任意一种钱币达到999后，无法再继续投入钱币，但可以继续购买  
                errordlg('Money in the machine is full laden, can not input, please call the staff','Error');% couldn't input
                return;
            end
            
            if app.EditField_5.Value ~= 100 &&...
               app.EditField_5.Value ~= 50 &&...
               app.EditField_5.Value ~= 20 &&...
               app.EditField_5.Value ~= 10 &&...
               app.EditField_5.Value ~= 5 &&...
               app.EditField_5.Value ~= 1 &&...
               app.EditField_5.Value ~= 0.5&&...
               app.EditField_5.Value ~= 0
                errordlg('Wrong Amount','Error'); % if money cast is ...
                                                       % not 100,50,20...
                                                       % ,10,5,1,0.5,0, 
                                                       % then it's wrong
                                                       % amount
                app.EditField_5.Value = 0;             % the money will be swallowed.  
                return;
            end
            
            if app.EditField_5.Value == 1
                app.a1 = app.a1+1;
                app.another.another.EditField_2.Value = app.a1; % the surplus of money1 +1 anbd display it in the maintenance interface 
            end
            
            if app.EditField_5.Value == 5
                app.a5 = app.a5+1;
                app.another.another.EditField_3.Value = app.a5; % the surplus of money5 +1 anbd display it in the maintenance interface 
            end
            
            if app.EditField_5.Value == 10
                app.a10 = app.a10+1;
                app.another.another.EditField_4.Value = app.a10; % the surplus of money10 +1 anbd display it in the maintenance interface 
            end
            
            if app.EditField_5.Value == 20
                app.a20 = app.a20+1;
                app.another.another.EditField_5.Value = app.a20; % the surplus of money20 +1 anbd display it in the maintenance interface 
            end
            
            if app.EditField_5.Value == 50
                app.a50 = app.a50+1;
                app.another.another.EditField_6.Value = app.a50; % the surplus of money50 +1 anbd display it in the maintenance interface 
            end
            
            if app.EditField_5.Value == 100
                app.a100 = app.a100+1;
                app.another.another.EditField_7.Value = app.a100; % the surplus of money100 +1 anbd display it in the maintenance interface 
            end
                  
            app.lft = app.lft + app.EditField_5.Value; % put into new money and display the amount 
            app.EditField_5.Value = 0;
            app.ThereareEditField.Value = app.lft;
           
        end

        % Button pushed function: BuyButton
        function BuyButtonPushed(app, event)
            if isequal(app.TextArea_2.Value,"Error")   % BUY::: if state is error,    
                errordlg('Mechanical failure','Error');% couldn't buy
                return;
            end
            if isequal(app.TextArea_2.Value,"Error2")   % BUY::: if state is error,    
                errordlg('Money in the machine is not enough, please call the staff','Error');% couldn't buy
                return;
            end
            
            if(app.lft - app.p<0)         % cost couldn't be larger than money put into 
                errordlg('Money not enough','Error');
                return;
            end
            if(app.s1 - app.c1 < 0)                  % check whether the items are enough
                errordlg('item1 not enough','Error');
                return;
            end
            if(app.s2 - app.c2 < 0)
                errordlg('item2 not enough','Error');
                return;
            end
            if(app.s3 - app.c3 < 0)
                errordlg('item3 not enough','Error');
                return;
            end
            if(app.s4 - app.c4 < 0)
                errordlg('item4 not enough','Error');
                return;
            end
            app.Item1TextArea.Value = num2str(app.c1); % display the number of mechandise purchased
            app.Item2TextArea_2.Value = num2str(app.c2);
            app.Item3TextArea.Value = num2str(app.c3);
            app.Item4TextArea.Value = num2str(app.c4);
            app.amount = app.amount + app.p;  % money earned improved when executing buy
            app.another.another.AlreadyObtainEditField.Value = app.amount; % display the changing earned money on the maintenance interface 
            
            app.s1 = app.s1 - app.c1; %update surplus
            app.s2 = app.s2 - app.c2; %update surplus
            app.s3 = app.s3 - app.c3; %update surplus
            app.s4 = app.s4 - app.c4; %update surplus          
            
            app.LeftEditField.Value = app.s1;             
            app.LeftEditField_2.Value = app.s2;             
            app.LeftEditField_3.Value = app.s3;          
            app.LeftEditField_4.Value = app.s4;
             
            app.another.another.s1 = app.s1; % synchronize surplus
            app.another.another.s2 = app.s2;
            app.another.another.s3 = app.s3;
            app.another.another.s4 = app.s4;
            app.another.another.LeftEditField.Value = app.another.another.s1;   %update surplus
            app.another.another.LeftEditField_2.Value = app.another.another.s2; %update surplus
            app.another.another.LeftEditField_3.Value = app.another.another.s3; %update surplus
            app.another.another.LeftEditField_4.Value = app.another.another.s4; %update surplus
            
            app.lft = app.lft - app.p; % the amount of money left changed after buying
            app.ThereareEditField.Value = app.lft; 
            app.p = 0; % cost should be cleared after finishing buying
            app.TotalCostEditField.Value = app.p;
            app.c1 = 0; % the amount of chosen merchandise(items) should be reset
            app.c2 = 0; % the amount of chosen merchandise(items) should be reset
            app.c3 = 0; % the amount of chosen merchandise(items) should be reset
            app.c4 = 0; % the amount of chosen merchandise(items) should be reset
            app.ChosenEditField.Value   = app.c1;
            app.ChosenEditField_2.Value = app.c2;
            app.ChosenEditField_3.Value = app.c3;
            app.ChosenEditField_4.Value = app.c4;
            
        end

        % Button pushed function: RefundButton
        function RefundButtonPushed(app, event)
            app.SUMEditField.Value = app.lft; % after refunding, the lft should be cleared 
            if app.lft >=100
                
                app.a100 =app.a100 - floor(app.lft/100);   % 此时lft值还不能改变，注意与下一语句的顺序；
                app.EditField_11.Value = floor(app.lft/100);
                app.lft = app.lft - floor(app.lft/100)*100;
                        
            end
            
            if app.lft >=50            
                app.a50 =app.a50 - floor(app.lft/50);
                app.EditField_10.Value = floor(app.lft/50);
                app.lft = app.lft - floor(app.lft/50)*50;
                
            end
            
            if app.lft >=20            
                 app.a20 =app.a20 - floor(app.lft/20);
                 app.EditField_9.Value = floor(app.lft/20);
                app.lft = app.lft - floor(app.lft/20)*20;
               
            end
            
            if app.lft >=10            
                app.a10 =app.a10 - floor(app.lft/10);
                app.EditField_8.Value = floor(app.lft/10);
                app.lft = app.lft - floor(app.lft/10)*10;
                
            end
            
            if app.lft >=5            
                app.a5 =app.a5 - floor(app.lft/5);
                app.EditField_7.Value = floor(app.lft/5);
                app.lft = app.lft - floor(app.lft/5)*5;
                
            end
            
            if app.lft >=1            
                
                if  app.a0 < floor(app.lft/1)
                    app.cEditField.Value = app.a0;
                    app.a0 =0;                %先吐一元硬币，再吐一元纸币 
                    app.EditField_6.Value = floor(app.lft/1)-app.a0;
                    app.a1 = app.a1 - (floor(app.lft/1)-app.a0);
                else
                    app.a0 = app.a0 -app.lft;
                    app.cEditField.Value = app.lft;
                    
                end
                app.lft = app.lft - floor(app.lft/1)*1;                
             end
             
            app.ThereareEditField.Value = app.lft;
            app.another.another.cEditField.Value = app.a0;
            app.another.another.EditField_2.Value = app.a1;
            app.another.another.EditField_3.Value = app.a5;
            app.another.another.EditField_4.Value = app.a10;
            app.another.another.EditField_5.Value = app.a20;
            app.another.another.EditField_6.Value = app.a50;
            app.another.another.EditField_7.Value = app.a100;
            
            app.ThereareEditField.Value = floor(app.lft/100);
            if (app.a0+app.a1)<4||app.a5<1||app.a10<1||app.a20<2||app.a50<1||app.a100<1 % 经过验证，必须满足能够找零
                app.TextArea_2 = "Error2";                % Eorror2 : Money in the machine is not enough
                app.TextArea_2.FontColor = [1.00,0.00,0.00];
            end
            
        end

        % Button pushed function: MaintenanceInterfaceButton
        function MaintenanceInterfaceButtonPushed(app, event)
            app.another = Password_Interface; % open password interface
        end

        % Button pushed function: FAKEButton
        function FAKEButtonPushed(app, event)
            if app.EditField_5.Value == 0
                return;
            end
            app.SUMEditField.Value = app.EditField_5.Value;
            app.EditField_5.Value = 0;
            errordlg('Fake money','Error'); % put in fake money and the money will be spat.    
        end

        % Button pushed function: COINButton
        function COINButtonPushed(app, event)
             app.a0 = app.a0+1; % 投入硬币
             if  app.a0>999
                 app.SUMEditField.Value = 1; % 达到999后，硬币无法投入，会自动吐出
                 app.a0 = app.a0-1;
                 return;
             end
             app.another.another.cEditField.Value = app.a0;
             app.lft = app.lft + 1;
             app.ThereareEditField.Value = app.lft;
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputmoneyButton = uibutton(app.UIFigure, 'push');
            app.InputmoneyButton.ButtonPushedFcn = createCallbackFcn(app, @InputmoneyButtonPushed, true);
            app.InputmoneyButton.Text = 'Input money';
            app.RefundButton = uibutton(app.UIFigure, 'push');
            app.RefundButton.ButtonPushedFcn = createCallbackFcn(app, @RefundButtonPushed, true);
            app.RefundButton.Text = 'Refund';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2￥';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3￥';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '5￥';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '10￥';
            app.ADDVendingmachineLabel = uilabel(app.UIFigure);
            app.ADDVendingmachineLabel.Text = 'ADD Vending machine';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'src=http___image.suning.cn_uimg_sop_commodity_201410271038254105_x.jpg&refer=http___image.suning.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'src=http___www.17qq.com_img_qqtouxiang_20295166.jpeg&refer=http___www.17qq.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'src=http___2ww.ds99.com_Public_uploads_shop_goods_53897130e8c16.jpg&refer=http___2ww.ds99.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'src=http___5b0988e595225.cdn.sohucs.com_images_20170929_54125a8ea66f41afaeff661ffc4bb958.jpeg&refer=http___5b0988e595225.cdn.sohucs.jpg';
            app.MaintenanceInterfaceButton = uibutton(app.UIFigure, 'push');
            app.MaintenanceInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @MaintenanceInterfaceButtonPushed, true);
            app.MaintenanceInterfaceButton.Text = 'Maintenance Interface';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Normal'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '-';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Item1';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Item2';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'Item3';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = 'Item4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '+';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '-';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '-';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '￥';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State：';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '￥';
            app.ChosenEditFieldLabel = uilabel(app.UIFigure);
            app.ChosenEditFieldLabel.Text = 'Chosen';
            app.ChosenEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChosenEditField_2Label = uilabel(app.UIFigure);
            app.ChosenEditField_2Label.Text = 'Chosen';
            app.ChosenEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ChosenEditField_3Label = uilabel(app.UIFigure);
            app.ChosenEditField_3Label.Text = 'Chosen';
            app.ChosenEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ChosenEditField_4Label = uilabel(app.UIFigure);
            app.ChosenEditField_4Label.Text = 'Chosen';
            app.TotalCostEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCostEditFieldLabel.Text = 'Total Cost：';
            app.TotalCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThereareEditFieldLabel = uilabel(app.UIFigure);
            app.ThereareEditFieldLabel.Text = 'There are ';
            app.ThereareEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '￥';
            app.BuyButton = uibutton(app.UIFigure, 'push');
            app.BuyButton.ButtonPushedFcn = createCallbackFcn(app, @BuyButtonPushed, true);
            app.BuyButton.Text = 'Buy';
            app.leftLabel = uilabel(app.UIFigure);
            app.leftLabel.Text = 'left';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.ChosenEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.SUMEditFieldLabel = uilabel(app.UIFigure);
            app.SUMEditFieldLabel.Text = 'SUM';
            app.SUMEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '￥';
            app.Item1TextAreaLabel = uilabel(app.UIFigure);
            app.Item1TextAreaLabel.Text = 'Item1';
            app.Item1TextArea = uitextarea(app.UIFigure);
            app.Item1TextArea.Value = {'0'};
            app.ExitofmerchandiseLabel = uilabel(app.UIFigure);
            app.ExitofmerchandiseLabel.Text = 'Exit of merchandise：';
            app.Item2TextArea_2Label = uilabel(app.UIFigure);
            app.Item2TextArea_2Label.Text = 'Item2';
            app.Item2TextArea_2 = uitextarea(app.UIFigure);
            app.Item2TextArea_2.Value = {'0'};
            app.Item3TextAreaLabel = uilabel(app.UIFigure);
            app.Item3TextAreaLabel.Text = 'Item3';
            app.Item3TextArea = uitextarea(app.UIFigure);
            app.Item3TextArea.Value = {'0'};
            app.Item4TextAreaLabel = uilabel(app.UIFigure);
            app.Item4TextAreaLabel.Text = 'Item4';
            app.Item4TextArea = uitextarea(app.UIFigure);
            app.Item4TextArea.Value = {'0'};
            app.LeftEditFieldLabel = uilabel(app.UIFigure);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditField_2Label = uilabel(app.UIFigure);
            app.LeftEditField_2Label.Text = 'Left';
            app.LeftEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditField_3Label = uilabel(app.UIFigure);
            app.LeftEditField_3Label.Text = 'Left';
            app.LeftEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditField_4Label = uilabel(app.UIFigure);
            app.LeftEditField_4Label.Text = 'Left';
            app.LeftEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.FAKEButton = uibutton(app.UIFigure, 'push');
            app.FAKEButton.ButtonPushedFcn = createCallbackFcn(app, @FAKEButtonPushed, true);
            app.FAKEButton.Text = 'FAKE';
            app.COINButton = uibutton(app.UIFigure, 'push');
            app.COINButton.ButtonPushedFcn = createCallbackFcn(app, @COINButtonPushed, true);
            app.COINButton.Text = 'COIN';
            app.coinLabel = uilabel(app.UIFigure);
            app.coinLabel.Text = '1   ￥(coin)';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '1    ￥';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = ' 5   ￥';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '10  ￥';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '20  ￥';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '50  ￥';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '100￥';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end