classdef Maintenance_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Item1Button                  matlab.ui.control.Button
        Item2Button                  matlab.ui.control.Button
        Item3Button                  matlab.ui.control.Button
        Item4Button                  matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        MaintenanceInterfaceLabel    matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        Button_5                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        Button_8                     matlab.ui.control.Button
        ChangeStateButton            matlab.ui.control.StateButton
        TextArea                     matlab.ui.control.TextArea
        BackButton                   matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        AlreadyObtainEditFieldLabel  matlab.ui.control.Label
        AlreadyObtainEditField       matlab.ui.control.NumericEditField
        LeftEditFieldLabel           matlab.ui.control.Label
        LeftEditField                matlab.ui.control.NumericEditField
        LeftEditField_2Label         matlab.ui.control.Label
        LeftEditField_2              matlab.ui.control.NumericEditField
        LeftEditField_3Label         matlab.ui.control.Label
        LeftEditField_3              matlab.ui.control.NumericEditField
        LeftEditField_4Label         matlab.ui.control.Label
        LeftEditField_4              matlab.ui.control.NumericEditField
        coinLabel                    matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        Label_7                      matlab.ui.control.Label
        EditField_7                  matlab.ui.control.NumericEditField
        ExtractButton                matlab.ui.control.Button
        cEditField                   matlab.ui.control.NumericEditField
        Button_9                     matlab.ui.control.Button
        Button_10                    matlab.ui.control.Button
        Button_11                    matlab.ui.control.Button
        Button_12                    matlab.ui.control.Button
        Button_13                    matlab.ui.control.Button
        Button_14                    matlab.ui.control.Button
        Button_15                    matlab.ui.control.Button
        Button_16                    matlab.ui.control.Button
        Button_17                    matlab.ui.control.Button
        Button_18                    matlab.ui.control.Button
        Button_19                    matlab.ui.control.Button
        Button_20                    matlab.ui.control.Button
        Button_21                    matlab.ui.control.Button
        Button_22                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        another;
        s1; %surplus of item1;
        s2; %surplus of item2;
        s3; %surplus of item3;
        s4; %surplus of item4;
        x;
        E;
        a0,a1,a5,a10,a20,a50,a100;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.s1 = 12; %initialization storage of cargo
             app.s2 = 10; 
             app.s3 = 8;
             app.s4 = 5;
             
             app.LeftEditField.Value = app.s1;
             app.LeftEditField_2.Value = app.s2;
             app.LeftEditField_3.Value = app.s3;
             app.LeftEditField_4.Value = app.s4;
             
             app.a0 = 100; app.a1 = 100; app.a5 = 100;%initialization storage of money
             app.a10 = 100; app.a20 =100;
             app.a50 = 100; app.a100 = 100;
             
            app.cEditField.Value = app.a0;
            app.EditField_2.Value = app.a1;
            app.EditField_3.Value = app.a5;
            app.EditField_4.Value = app.a10;
            app.EditField_5.Value = app.a20;
            app.EditField_6.Value = app.a50;
            app.EditField_7.Value = app.a100;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.another = Main_Interface;
            
        end

        % Value changed function: ChangeStateButton
        function ChangeStateButtonValueChanged(app, event)
        if isequal(app.TextArea.Value,"Normal")
            app.TextArea.Value = "Error";
            app.TextArea.FontColor = [1.00,0.00,0.00];
            app.another.TextArea_2.Value = "Error";
            app.another.TextArea_2.FontColor = [1.00,0.00,0.00];
        else 
            app.TextArea.Value = "Normal";
            app.TextArea.FontColor = [0.00,1.00,0.00];
            app.another.TextArea_2.Value = "Normal";
            app.another.TextArea_2.FontColor = [0.00,1.00,0.00];
        end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.s1 = app.s1 + 1;            % item1 +
            app.LeftEditField.Value = app.s1;
            app.another.s1 = app.s1; %synchronization
            app.another.LeftEditField.Value = app.another.s1;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if(app.s1 == 0)               % item1 -
                return;
            end
            app.s1 = app.s1 - 1;
            app.LeftEditField.Value = app.s1;
            app.another.s1 = app.s1; %synchronization
            app.another.LeftEditField.Value = app.another.s1;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.s2 = app.s2 + 1;          % item2 +
            app.LeftEditField_2.Value = app.s2;
            app.another.s2 = app.s2; %synchronization
            app.another.LeftEditField_2.Value = app.another.s2;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if(app.s2 == 0)               % item2 -
                return; 
            end
            app.s2 = app.s2 - 1;
            app.LeftEditField_2.Value = app.s2;
            app.another.s2 = app.s2; %synchronization
            app.another.LeftEditField_2.Value = app.another.s2;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.s3 = app.s3 + 1;         % item3 +
            app.LeftEditField_3.Value = app.s3;
            app.another.s3 = app.s3; %synchronization
            app.another.LeftEditField_3.Value = app.another.s3;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if(app.s3 == 0)              % item3 -
                return;
            end
            app.s3 = app.s3 - 1;
            app.LeftEditField_3.Value = app.s3;
            app.another.s3 = app.s3; %synchronization
            app.another.LeftEditField_3.Value = app.another.s3;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.s4 = app.s4 + 1;      % item4 +
            app.LeftEditField_4.Value = app.s4;
            app.another.s4 = app.s4; %synchronization
            app.another.LeftEditField_4.Value = app.another.s4;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if(app.s4 == 0)           % item4 -
                return;
            end
            app.s4 = app.s4 - 1;
            app.LeftEditField_4.Value = app.s4;
            app.another.s4 = app.s4; %synchronization
            app.another.LeftEditField_4.Value = app.another.s4;
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            %app.E = app.cEditField.Value*1 + app.EditField_2.Value*1 + app.EditField_3.Value*5 +...
                %app.EditField_4.Value*10 + app.EditField_5.Value*20 + app.EditField_6.Value*50 + app.EditField_7.Value*100;
            %app.another.ExitofmoneyEditField.Value = app.E;
            app.x = 0;
            app.cEditField.Value = app.x;
            app.EditField_2.Value = app.x;
            app.EditField_3.Value = app.x;
            app.EditField_4.Value = app.x;
            app.EditField_5.Value = app.x;
            app.EditField_6.Value = app.x;
            app.EditField_7.Value = app.x;
            app.AlreadyObtainEditField.Value = app.x;
            
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.a0==0
                return;
            end
            app.a0 = app.a0 - 1;
            app.cEditField.Value = app.a0;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if app.a0==999
                return;
            end
            app.a0 = app.a0 + 1;
            app.cEditField.Value = app.a0;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.a1==0
                return;
            end
            app.a1 = app.a1 - 1;
             app.EditField_2.Value = app.a1;
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            if app.a1==999
                return;
            end
            app.a1 = app.a1 + 1;
            app.EditField_2.Value = app.a1;
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.a5==0
                return;
            end
            app.a5 = app.a5 - 1;
             app.EditField_3.Value = app.a5;
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            if app.a5==999
                return;
            end
            app.a5 = app.a5 + 1;
             app.EditField_3.Value = app.a5;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            if app.a10 == 0
                return;
            end
            app.a10 = app.a10 - 1;
             app.EditField_4.Value = app.a10;
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            if app.a10==999
                return;
            end
            app.a10 = app.a10 + 1;
             app.EditField_4.Value = app.a10;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
             if app.a20 == 0
                return;
            end
            app.a20 = app.a20 - 1;
             app.EditField_5.Value = app.a20;
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
             if app.a20 == 999
                return;
            end
            app.a20 = app.a20 + 1;
             app.EditField_5.Value = app.a20;
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            if app.a50 == 0
                return;
            end
            app.a50 = app.a50 - 1;
             app.EditField_6.Value = app.a50;
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
             if app.a50 == 999
                return;
            end
            app.a50 = app.a50 + 1;
             app.EditField_6.Value = app.a50;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            if app.a100 == 0
                return;
            end
            app.a100 = app.a100 - 1;
             app.EditField_7.Value = app.a100;
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            if app.a100 == 999
                return;
            end
            app.a100 = app.a100 + 1;
             app.EditField_7.Value = app.a100;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Item1Button = uibutton(app.UIFigure, 'push');
            app.Item1Button.Text = 'Item1';
            app.Item2Button = uibutton(app.UIFigure, 'push');
            app.Item2Button.Text = 'Item2';
            app.Item3Button = uibutton(app.UIFigure, 'push');
            app.Item3Button.Text = 'Item3';
            app.Item4Button = uibutton(app.UIFigure, 'push');
            app.Item4Button.Text = 'Item4';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'src=http___image.suning.cn_uimg_sop_commodity_201410271038254105_x.jpg&refer=http___image.suning.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'src=http___www.17qq.com_img_qqtouxiang_20295166.jpeg&refer=http___www.17qq.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'src=http___2ww.ds99.com_Public_uploads_shop_goods_53897130e8c16.jpg&refer=http___2ww.ds99.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'src=http___5b0988e595225.cdn.sohucs.com_images_20170929_54125a8ea66f41afaeff661ffc4bb958.jpeg&refer=http___5b0988e595225.cdn.sohucs.jpg';
            app.MaintenanceInterfaceLabel = uilabel(app.UIFigure);
            app.MaintenanceInterfaceLabel.Text = 'Maintenance Interface';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '+';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '-';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '-';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '-';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '-';
            app.ChangeStateButton = uibutton(app.UIFigure, 'state');
            app.ChangeStateButton.ValueChangedFcn = createCallbackFcn(app, @ChangeStateButtonValueChanged, true);
            app.ChangeStateButton.Text = 'Change State';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Normal'};
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '￥';
            app.AlreadyObtainEditFieldLabel = uilabel(app.UIFigure);
            app.AlreadyObtainEditFieldLabel.Text = 'Already Obtain';
            app.AlreadyObtainEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditFieldLabel = uilabel(app.UIFigure);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditField_2Label = uilabel(app.UIFigure);
            app.LeftEditField_2Label.Text = 'Left';
            app.LeftEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditField_3Label = uilabel(app.UIFigure);
            app.LeftEditField_3Label.Text = 'Left';
            app.LeftEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LeftEditField_4Label = uilabel(app.UIFigure);
            app.LeftEditField_4Label.Text = 'Left';
            app.LeftEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.coinLabel = uilabel(app.UIFigure);
            app.coinLabel.Text = '1   ￥(coin)';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1    ￥';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = ' 5    ￥';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '10  ￥';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '20  ￥';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '50  ￥';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '100￥';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.ExtractButton = uibutton(app.UIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '-';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '+';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '-';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '-';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '-';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '-';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '-';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '-';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '+';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '+';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '+';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '+';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '+';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Maintenance_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end